classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        JointPositionsTab       matlab.ui.container.Tab
        Joint1Box               matlab.ui.control.NumericEditField
        Joint2Box               matlab.ui.control.NumericEditField
        Joint3Box               matlab.ui.control.NumericEditField
        Joint4Box               matlab.ui.control.NumericEditField
        Joint5Box               matlab.ui.control.NumericEditField
        Joint6Box               matlab.ui.control.NumericEditField
        Joint7Box               matlab.ui.control.NumericEditField
        Joint1SliderLabel       matlab.ui.control.Label
        Joint1Slider            matlab.ui.control.Slider
        Joint2SliderLabel       matlab.ui.control.Label
        Joint2Slider            matlab.ui.control.Slider
        Joint3SliderLabel       matlab.ui.control.Label
        Joint3Slider            matlab.ui.control.Slider
        Joint4SliderLabel       matlab.ui.control.Label
        Joint4Slider            matlab.ui.control.Slider
        Joint5SliderLabel       matlab.ui.control.Label
        Joint5Slider            matlab.ui.control.Slider
        Joint6SliderLabel       matlab.ui.control.Label
        Joint6Slider            matlab.ui.control.Slider
        Joint7SliderLabel       matlab.ui.control.Label
        Joint7Slider            matlab.ui.control.Slider
        EndeffectorPositionTab  matlab.ui.container.Tab
        YSpinnerLabel           matlab.ui.control.Label
        YSpinner                matlab.ui.control.Spinner
        ZSpinnerLabel           matlab.ui.control.Label
        ZSpinner                matlab.ui.control.Spinner
        XSpinnerLabel           matlab.ui.control.Label
        XSpinner                matlab.ui.control.Spinner
        FetchPositionTab        matlab.ui.container.Tab
        StartButton             matlab.ui.control.StateButton
        StopButton              matlab.ui.control.StateButton
        ResetButton             matlab.ui.control.StateButton
        OpenGripperButton       matlab.ui.control.StateButton
        CloseGripperButton      matlab.ui.control.StateButton
        EmergencyStopButton     matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        robot % Description
    end
    
    methods (Access = public)
        % pass in robot object
        function addRobot(app, robot)
            app.robot = robot;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Joint1Box
        function Joint1BoxValueChanged(app, event)
            app.Joint1Slider.Value = app.Joint1Box.Value;
            q = app.robot.model.getpos;
            q(1) = deg2rad(app.Joint1Box.Value);
            app.robot.Move2JointState(q, 100);
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            app.Joint1Box.Value = app.Joint1Slider.Value;
            q = app.robot.model.getpos;
            q(1) = deg2rad(app.Joint1Box.Value);
        
            app.robot.Move2JointState(q, 100);
            
        end

        % Value changed function: Joint2Box
        function Joint2BoxValueChanged(app, event)
            app.Joint2Slider.Value = app.Joint2Box.Value;
            q = app.robot.model.getpos;
            q(2) = deg2rad(app.Joint2Box.Value);
            app.robot.Move2JointState(q, 100);
            
        end

        % Value changed function: Joint3Box
        function Joint3BoxValueChanged(app, event)
            app.Joint3Slider.Value = app.Joint3Box.Value;
            q = app.robot.model.getpos;
            q(3) = deg2rad(app.Joint3Box.Value);
            app.robot.Move2JointState(q, 100);
            
        end

        % Value changed function: Joint4Box
        function Joint4BoxValueChanged(app, event)
            app.Joint4Slider.Value = app.Joint4Box.Value;
            q = app.robot.model.getpos;
            q(4) = deg2rad(app.Joint4Box.Value);
            app.robot.Move2JointState(q, 100);
            
        end

        % Value changed function: Joint5Box
        function Joint5BoxValueChanged(app, event)
            app.Joint5Slider.Value = app.Joint5Box.Value;
            q = app.robot.model.getpos;
            q(5) = deg2rad(app.Joint5Box.Value);
            app.robot.Move2JointState(q, 100);
            
        end

        % Value changed function: Joint6Box
        function Joint6BoxValueChanged(app, event)
            app.Joint6Slider.Value = app.Joint6Box.Value;
            q = app.robot.model.getpos;
            q(6) = deg2rad(app.Joint6Box.Value);
            app.robot.Move2JointState(q, 100);
            
        end

        % Value changed function: Joint7Box
        function Joint7BoxValueChanged(app, event)
            app.Joint7Slider.Value = app.Joint7Box.Value;
            q = app.robot.model.getpos;
            q(7) = deg2rad(app.Joint7Box.Value);
            app.robot.Move2JointState(q, 100);
            
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            app.Joint2Box.Value = app.Joint2Slider.Value;
            q = app.robot.model.getpos;
            q(2) = deg2rad(app.Joint2Box.Value);
            app.robot.Move2JointState(q, 100);
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            app.Joint3Box.Value = app.Joint3Slider.Value;
            q = app.robot.model.getpos;
            q(3) = deg2rad(app.Joint3Box.Value);
            app.robot.Move2JointState(q, 100);
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            app.Joint4Box.Value = app.Joint4Slider.Value;
            q = app.robot.model.getpos;
            q(4) = deg2rad(app.Joint4Box.Value);
            app.robot.Move2JointState(q, 100);
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            app.Joint5Box.Value = app.Joint5Slider.Value;
            q = app.robot.model.getpos;
            q(5) = deg2rad(app.Joint5Box.Value);
            app.robot.Move2JointState(q, 100);
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            app.Joint6Box.Value = app.Joint6Slider.Value;
            q = app.robot.model.getpos;
            q(6) = deg2rad(app.Joint6Box.Value);
            app.robot.Move2JointState(q, 100);
        end

        % Value changed function: Joint7Slider
        function Joint7SliderValueChanged(app, event)
            app.Joint7Box.Value = app.Joint7Slider.Value;
            q = app.robot.model.getpos;
            q(7) = deg2rad(app.Joint7Box.Value);
            app.robot.Move2JointState(q, 100);
        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            app.Joint1Box.Value = 1.32;
            app.Joint2Box.Value = 1.4;
            app.Joint3Box.Value = -0.2;
            app.Joint4Box.Value = 1.72;
            app.Joint5Box.Value = 0;
            app.Joint6Box.Value = 1.66;
            app.Joint7Box.Value = 0;
            
            app.Joint1Slider.Value = 1.32;
            app.Joint2Slider.Value = 1.4;
            app.Joint3Slider.Value = -0.2;
            app.Joint4Slider.Value = 1.72;
            app.Joint5Slider.Value = 0;
            app.Joint6Slider.Value = 1.66;
            app.Joint7Slider.Value = 0;
            
            q = [app.Joint1Box.Value,app.Joint2Box.Value, app.Joint3Box.Value, app.Joint4Box.Value, app.Joint5Box.Value, app.Joint6Box.Value, app.Joint7Box.Value ];
            app.robot.Move2JointState(q, 100);
            
        end

        % Callback function
        function SetButton_2ValueChanged(app, event)

            
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            pose = [app.XSpinner.Value, app.YSpinner.Value, app.ZSpinner.Value];
            app.robot.model.Move2Pose(pose, 2);
            
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            pose = [app.XSpinner.Value, app.YSpinner.Value, app.ZSpinner.Value];
            app.robot.model.Move2Pose(pose, 2);
            
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            pose = [app.XSpinner.Value, app.YSpinner.Value, app.ZSpinner.Value];
            app.robot.model.Move2Pose(pose, 2);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.JointPositionsTab = uitab(app.TabGroup);
            app.JointPositionsTab.Title = 'Joint Positions';
            app.Joint1Box = uieditfield(app.JointPositionsTab, 'numeric');
            app.Joint1Box.Limits = [-92 92];
            app.Joint1Box.ValueChangedFcn = createCallbackFcn(app, @Joint1BoxValueChanged, true);
            app.Joint1Box.Value = 1.32;
            app.Joint2Box = uieditfield(app.JointPositionsTab, 'numeric');
            app.Joint2Box.Limits = [-87 70];
            app.Joint2Box.ValueChangedFcn = createCallbackFcn(app, @Joint2BoxValueChanged, true);
            app.Joint2Box.Value = 1.4;
            app.Joint3Box = uieditfield(app.JointPositionsTab, 'numeric');
            app.Joint3Box.Limits = [-360 360];
            app.Joint3Box.ValueChangedFcn = createCallbackFcn(app, @Joint3BoxValueChanged, true);
            app.Joint3Box.Value = -0.2;
            app.Joint4Box = uieditfield(app.JointPositionsTab, 'numeric');
            app.Joint4Box.Limits = [-129 129];
            app.Joint4Box.ValueChangedFcn = createCallbackFcn(app, @Joint4BoxValueChanged, true);
            app.Joint4Box.Value = 1.72;
            app.Joint5Box = uieditfield(app.JointPositionsTab, 'numeric');
            app.Joint5Box.Limits = [-360 360];
            app.Joint5Box.ValueChangedFcn = createCallbackFcn(app, @Joint5BoxValueChanged, true);
            app.Joint6Box = uieditfield(app.JointPositionsTab, 'numeric');
            app.Joint6Box.Limits = [-125 125];
            app.Joint6Box.ValueChangedFcn = createCallbackFcn(app, @Joint6BoxValueChanged, true);
            app.Joint6Box.Value = 1.66;
            app.Joint7Box = uieditfield(app.JointPositionsTab, 'numeric');
            app.Joint7Box.Limits = [-360 360];
            app.Joint7Box.ValueChangedFcn = createCallbackFcn(app, @Joint7BoxValueChanged, true);
            app.Joint1SliderLabel = uilabel(app.JointPositionsTab);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.JointPositionsTab);
            app.Joint1Slider.Limits = [-92 92];
            app.Joint1Slider.MajorTicks = [-92 0 92];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint1Slider.MinorTicks = [];
            app.Joint1Slider.Value = 1.32;
            app.Joint2SliderLabel = uilabel(app.JointPositionsTab);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.JointPositionsTab);
            app.Joint2Slider.Limits = [-87 70];
            app.Joint2Slider.MajorTicks = [-87 0 70];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.MinorTicks = [];
            app.Joint2Slider.Value = 1.4;
            app.Joint3SliderLabel = uilabel(app.JointPositionsTab);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.JointPositionsTab);
            app.Joint3Slider.Limits = [-360 360];
            app.Joint3Slider.MajorTicks = [-360 0 360];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.MinorTicks = [];
            app.Joint3Slider.Value = 1.32;
            app.Joint4SliderLabel = uilabel(app.JointPositionsTab);
            app.Joint4SliderLabel.Text = 'Joint4';
            app.Joint4Slider = uislider(app.JointPositionsTab);
            app.Joint4Slider.Limits = [-129 129];
            app.Joint4Slider.MajorTicks = [-129 0 129];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint4Slider.MinorTicks = [];
            app.Joint4Slider.Value = 1.32;
            app.Joint5SliderLabel = uilabel(app.JointPositionsTab);
            app.Joint5SliderLabel.Text = 'Joint5';
            app.Joint5Slider = uislider(app.JointPositionsTab);
            app.Joint5Slider.Limits = [-360 360];
            app.Joint5Slider.MajorTicks = [-360 0 360];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint5Slider.MinorTicks = [];
            app.Joint5Slider.Value = 1.32;
            app.Joint6SliderLabel = uilabel(app.JointPositionsTab);
            app.Joint6SliderLabel.Text = 'Joint6';
            app.Joint6Slider = uislider(app.JointPositionsTab);
            app.Joint6Slider.Limits = [-125 125];
            app.Joint6Slider.MajorTicks = [-125 0 125];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint6Slider.MinorTicks = [];
            app.Joint6Slider.Value = 1.32;
            app.Joint7SliderLabel = uilabel(app.JointPositionsTab);
            app.Joint7SliderLabel.Text = 'Joint7';
            app.Joint7Slider = uislider(app.JointPositionsTab);
            app.Joint7Slider.Limits = [-360 360];
            app.Joint7Slider.MajorTicks = [-360 0 360];
            app.Joint7Slider.ValueChangedFcn = createCallbackFcn(app, @Joint7SliderValueChanged, true);
            app.Joint7Slider.MinorTicks = [];
            app.Joint7Slider.Value = 1.32;
            app.EndeffectorPositionTab = uitab(app.TabGroup);
            app.EndeffectorPositionTab.Title = 'End-effector Position';
            app.YSpinnerLabel = uilabel(app.EndeffectorPositionTab);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.EndeffectorPositionTab);
            app.YSpinner.Limits = [-10 10];
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.EndeffectorPositionTab);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.EndeffectorPositionTab);
            app.ZSpinner.Limits = [-10 10];
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.XSpinnerLabel = uilabel(app.EndeffectorPositionTab);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.EndeffectorPositionTab);
            app.XSpinner.Limits = [-10 10];
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.FetchPositionTab = uitab(app.TabGroup);
            app.FetchPositionTab.Title = 'Fetch Position';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.OpenGripperButton = uibutton(app.UIFigure, 'state');
            app.OpenGripperButton.Text = 'Open Gripper';
            app.CloseGripperButton = uibutton(app.UIFigure, 'state');
            app.CloseGripperButton.Text = 'Close Gripper';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'state');
            app.EmergencyStopButton.Text = 'Emergency Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end